classdef Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea                  matlab.ui.control.TextArea
        MemoryPanel               matlab.ui.container.Panel
        MCButton                  matlab.ui.control.Button
        MSButton_2                matlab.ui.control.Button
        MRButton                  matlab.ui.control.Button
        MButton                   matlab.ui.control.Button
        Memory                    matlab.ui.control.NumericEditField
        MButton_2                 matlab.ui.control.Button
        TrigPanel                 matlab.ui.container.Panel
        sinxButton                matlab.ui.control.Button
        cosxButton                matlab.ui.control.Button
        tanxButton                matlab.ui.control.Button
        sinhxButton               matlab.ui.control.Button
        coshxButton               matlab.ui.control.Button
        tanhxButton               matlab.ui.control.Button
        arcsinxButton             matlab.ui.control.Button
        arccosxButton             matlab.ui.control.Button
        arctanxButton             matlab.ui.control.Button
        arsinhxButton             matlab.ui.control.Button
        arcoshxButton             matlab.ui.control.Button
        artanhxButton             matlab.ui.control.Button
        AngleTypeSwitchLabel      matlab.ui.control.Label
        AngleTypeSwitch           matlab.ui.control.Switch
        BasicPanel                matlab.ui.container.Panel
        numberone                 matlab.ui.control.Button
        numberseven               matlab.ui.control.Button
        numbereight               matlab.ui.control.Button
        numbernine                matlab.ui.control.Button
        numberfour                matlab.ui.control.Button
        numberfive                matlab.ui.control.Button
        numbersix                 matlab.ui.control.Button
        addition                  matlab.ui.control.Button
        numberzero                matlab.ui.control.Button
        buttonEqual               matlab.ui.control.Button
        subtraction               matlab.ui.control.Button
        numbertwo                 matlab.ui.control.Button
        numberthree               matlab.ui.control.Button
        divide                    matlab.ui.control.Button
        multi                     matlab.ui.control.Button
        LeftBracket               matlab.ui.control.Button
        RightBracket              matlab.ui.control.Button
        piButton                  matlab.ui.control.Button
        buttonEqual_2             matlab.ui.control.Button
        ANSButton                 matlab.ui.control.Button
        DELButton                 matlab.ui.control.Button
        multi_2                   matlab.ui.control.Button
        GraphPanel                matlab.ui.container.Panel
        aButton                   matlab.ui.control.Button
        bButton                   matlab.ui.control.Button
        cButton                   matlab.ui.control.Button
        aoutput                   matlab.ui.control.EditField
        boutput                   matlab.ui.control.EditField
        coutput                   matlab.ui.control.EditField
        SolveQuad                 matlab.ui.control.Button
        RootOutput                matlab.ui.control.TextArea
        UIAxes                    matlab.ui.control.UIAxes
        ScientificOperationPanel  matlab.ui.container.Panel
        lnxButton                 matlab.ui.control.Button
        exButton                  matlab.ui.control.Button
        xButton_3                 matlab.ui.control.Button
        xButton                   matlab.ui.control.Button
        xButton_2                 matlab.ui.control.Button
        log10xButton              matlab.ui.control.Button
        RandButton                matlab.ui.control.Button
        angleButton               matlab.ui.control.Button
        xButton_4                 matlab.ui.control.Button
        x3Button                  matlab.ui.control.Button
        xyButton                  matlab.ui.control.Button
        x2Button                  matlab.ui.control.Button
        x13Button                 matlab.ui.control.Button
        x05Button                 matlab.ui.control.Button
        x1yButton                 matlab.ui.control.Button
        storeY                    matlab.ui.control.Button
        ClearYButton              matlab.ui.control.Button
        yValue                    matlab.ui.control.NumericEditField
        eButton                   matlab.ui.control.Button
        TextArea_2                matlab.ui.control.TextArea
        ACButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LeftBracket, RightBracket, 
        % addition, buttonEqual_2, divide, eButton, multi, multi_2, 
        % numbereight, numberfive, numberfour, numbernine, 
        % numberone, numberseven, numbersix, numberthree, 
        % numbertwo, numberzero, piButton, subtraction
        function numberonePushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text];
        end

        % Button pushed function: ACButton
        function ACButtonPushed(app, event)
            app.TextArea.Value = '';
        end

        % Button pushed function: buttonEqual
        function buttonEqualPushed(app, event)
           try
                if ~isempty(app.TextArea.Value)
                    if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))));
                    else
                    app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text ...
                    num2str( eval(app.TextArea.Value{1}))];                   
                    end
                end            
                    catch
               app.TextArea.Value = 'Error!';                
           end           
        end

        % Button pushed function: sinxButton
        function sinxButtonPushed(app, event)
            mul = 1;
            if app.AngleTypeSwitch.Value == "Deg"
                mul = pi/180;
            end
         
            
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(sin(mul*eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( sin(mul* eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
           end
        end

        % Button pushed function: cosxButton
        function cosxButtonPushed(app, event)
            mul = 1;
            if app.AngleTypeSwitch.Value == "Deg"
                mul = pi/180;
            end
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(cos(mul*eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( cos(mul* eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: tanxButton
        function tanxButtonPushed(app, event)
            mul = 1;
            if app.AngleTypeSwitch.Value == "Deg"
                mul = pi/180;
            end
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(tan(mul*eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( tan(mul* eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: sinhxButton
        function sinhxButtonPushed(app, event)
            mul = 1;
            
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(sinh(mul*eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( sinh(mul* eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: coshxButton
        function coshxButtonPushed(app, event)
            mul = 1;
           
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(cosh(mul*eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( cosh(mul* eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: tanhxButton
        function tanhxButtonPushed(app, event)
            mul = 1;
            
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(tanh(mul*eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( tanh(mul* eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: arcsinxButton
        function arcsinxButtonPushed(app, event)
            mul = 1;
            if app.AngleTypeSwitch.Value == "Deg"
                mul = 180/pi;
            end
            
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(mul*asin(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( mul*asin( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: arccosxButton
        function arccosxButtonPushed(app, event)
           mul = 1;
            if app.AngleTypeSwitch.Value == "Deg"
                mul = 180/pi;
            end
            
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(mul*acos(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( mul*acos( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: arctanxButton
        function arctanxButtonPushed(app, event)
            mul = 1;
            if app.AngleTypeSwitch.Value == "Deg"
                mul = 180/pi;
            end
            
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(mul*atan(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( mul*atan( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: arsinhxButton
        function arsinhxButtonPushed(app, event)
            mul = 1;
            
            
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(asinh(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( asinh( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: arcoshxButton
        function arcoshxButtonPushed(app, event)
            mul = 1;
            
            
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(acosh(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( acosh( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: artanhxButton
        function artanhxButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(atanh(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( atanh( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: lnxButton
        function lnxButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(log(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( log( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: log10xButton
        function log10xButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(log10(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( log10( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: exButton
        function exButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(exp(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( exp( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(factorial(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( factorial( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: xButton_2
        function xButton_2Pushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(10^(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( 10^( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: RandButton
        function RandButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(rand + (eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( rand + ( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: xButton_3
        function xButton_3Pushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(1/(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( 1/( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: angleButton
        function angleButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(angle(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( angle( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: xButton_4
        function xButton_4Pushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(abs(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')))) );
                else
                    app.TextArea.Value = num2str( abs( eval(app.TextArea.Value{1})));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: ANSButton
        function ANSButtonPushed(app, event)
          
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str(-1*eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))) );
                else
                    app.TextArea.Value = num2str( eval(app.TextArea.Value{1}) * -1);                    
                end
            catch
              app.TextArea.Value = 'Error!';
           end
        end

        % Button pushed function: storeY
        function storeYButtonPushed(app, event)
            
            try
                if contains(app.TextArea.Value,'=')
                    app.yValue.Value = eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')) );
                else
                    app.yValue.Value = eval(app.TextArea.Value{1});                    
                end
           catch
               app.TextArea.Value = 'Error! - This can not be stored';
            end
        end

        % Button pushed function: ClearYButton
        function ClearYButtonPushed(app, event)
            app.yValue.Value = 1;
        end

        % Button pushed function: x2Button
        function x2ButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str((eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))))^2 );
                else
                    app.TextArea.Value = num2str( ( eval(app.TextArea.Value{1}))^2);                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: x05Button
        function x05ButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str((eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))))^0.5 );
                else
                    app.TextArea.Value = num2str( ( eval(app.TextArea.Value{1}))^0.5);                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: x13Button
        function x13ButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str((eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))))^(1/3) );
                else
                    app.TextArea.Value = num2str( ( eval(app.TextArea.Value{1}))^(1/3));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: x1yButton
        function x1yButtonPushed(app, event)
          
            try
                y = app.yValue.Value;
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str((eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))))^(1/y) );
                else
                    app.TextArea.Value = num2str( ( eval(app.TextArea.Value{1}))^(1/y));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: xyButton
        function xyButtonPushed(app, event)
            try
                y = app.yValue.Value;
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str((eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))))^(y) );
                else
                    app.TextArea.Value = num2str( ( eval(app.TextArea.Value{1}))^(y));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: x3Button
        function x3ButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = num2str((eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))))^(3) );
                else
                    app.TextArea.Value = num2str( ( eval(app.TextArea.Value{1}))^(3));                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: MCButton
        function MCButtonPushed(app, event)
            app.Memory.Value = 0;
        end

        % Button pushed function: MSButton_2
        function MSButton_2Pushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.Memory.Value = eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'=')) );
                else
                    app.Memory.Value = eval(app.TextArea.Value{1});                    
                end
           catch
               app.TextArea.Value = 'Error! - This can not be stored';
           end
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')                    
                    app.TextArea.Value = [num2str(eval(eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))) + app.Memory.Value)];
                    
                else
                    app.TextArea.Value = [num2str(eval(app.TextArea.Value{1}) + app.Memory.Value)];                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: MButton_2
        function MButton_2Pushed(app, event)
            try
                if contains(app.TextArea.Value,'=')                    
                    app.TextArea.Value = [num2str(eval(eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))) - app.Memory.Value)];
                    
                else
                    app.TextArea.Value = [num2str(eval(app.TextArea.Value{1}) - app.Memory.Value)];                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: MRButton
        function MRButtonPushed(app, event)

            try
                if contains(app.TextArea.Value,'=')                    
                    app.TextArea.Value = [num2str(eval(eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))) * app.Memory.Value)];
                    
                else
                    app.TextArea.Value = [num2str(eval(app.TextArea.Value{1}) * app.Memory.Value)];                    
                end
            catch
              app.TextArea.Value = 'Error!';
            end
        end

        % Button pushed function: aButton
        function aButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.aoutput.Value = num2str(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))) );
                else
                    app.aoutput.Value = num2str(eval(app.TextArea.Value{1}));                    
                end
            catch
              app.TextArea.Value = 'Error! - This can not be stored';
           end
        end

        % Button pushed function: bButton
        function bButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.boutput.Value = num2str(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))) );
                else
                    app.boutput.Value = num2str(eval(app.TextArea.Value{1}));                   
                end
           catch
               app.TextArea.Value = 'Error! - This can not be stored';
           end
        end

        % Button pushed function: cButton
        function cButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.coutput.Value = num2str(eval( eraseBetween(app.TextArea.Value{1},1,strfind(app.TextArea.Value{1},'='))) );
                else
                    app.coutput.Value = num2str(eval(app.TextArea.Value{1}));                    
                end
           catch
               app.TextArea.Value = 'Error! - This can not be stored';
           end
        end

        % Button pushed function: SolveQuad
        function SolveQuadButtonPushed(app, event)
                try
                a = str2double(app.aoutput.Value);
                b = str2double(app.boutput.Value);
                c = str2double(app.coutput.Value);
                
            if a ~= 0
                
                p = [a b c];
                ru = roots(p);
                if ru(1) == ru(2)
                    app.RootOutput.Value =  "The repeated root is: " + string(ru(1));
                else
                    app.RootOutput.Value =  "Root 1 is: " + string(ru(1)) + " and Root 2 is: " + string(ru(2));
                end
                try
                x = -10:0.1:10;
                y = a*x.^2 + b*x + c;                                
                plot(app.UIAxes,x,y,'k');
                catch
                    app.TextArea.Value = 'Can not draw this graph!';
                end
                
            elseif b ~= 0
                p = [b c];
                ru = roots(p);
                x = -10:0.1:10;
                y = b*x + c;                                
                plot(app.UIAxes,x,y,'k');
                app.RootOutput.Value =  "The root is: " + string(ru);
            else
                app.RootOutput.Value = "Roots can not be calculated";                
            end
            
                catch
                    app.TextArea.Value = 'Error!';
                end


        end

        % Button pushed function: DELButton
        function DELButtonPushed(app, event)
            try
                if contains(app.TextArea.Value,'=')
                    app.TextArea.Value = app.TextArea.Value{1}(1:strfind(app.TextArea.Value{1},'=') - 1);                
                elseif ~isempty(app.TextArea.Value)
                    app.TextArea.Value = app.TextArea.Value{1}(1:end -1);                                   
                end
           catch
               %app.TextArea.Value = 'Error!';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'0'};
            app.MemoryPanel = uipanel(app.UIFigure);
            app.MemoryPanel.Title = 'Memory';
            app.MCButton = uibutton(app.MemoryPanel, 'push');
            app.MCButton.ButtonPushedFcn = createCallbackFcn(app, @MCButtonPushed, true);
            app.MCButton.Text = 'MC';
            app.MSButton_2 = uibutton(app.MemoryPanel, 'push');
            app.MSButton_2.ButtonPushedFcn = createCallbackFcn(app, @MSButton_2Pushed, true);
            app.MSButton_2.Text = 'MS';
            app.MRButton = uibutton(app.MemoryPanel, 'push');
            app.MRButton.ButtonPushedFcn = createCallbackFcn(app, @MRButtonPushed, true);
            app.MRButton.Text = 'MR';
            app.MButton = uibutton(app.MemoryPanel, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M+';
            app.Memory = uieditfield(app.MemoryPanel, 'numeric');
            app.MButton_2 = uibutton(app.MemoryPanel, 'push');
            app.MButton_2.ButtonPushedFcn = createCallbackFcn(app, @MButton_2Pushed, true);
            app.MButton_2.Text = 'M-';
            app.TrigPanel = uipanel(app.UIFigure);
            app.TrigPanel.Title = 'Trig';
            app.sinxButton = uibutton(app.TrigPanel, 'push');
            app.sinxButton.ButtonPushedFcn = createCallbackFcn(app, @sinxButtonPushed, true);
            app.sinxButton.Text = 'sin(x)';
            app.cosxButton = uibutton(app.TrigPanel, 'push');
            app.cosxButton.ButtonPushedFcn = createCallbackFcn(app, @cosxButtonPushed, true);
            app.cosxButton.Text = 'cos(x)';
            app.tanxButton = uibutton(app.TrigPanel, 'push');
            app.tanxButton.ButtonPushedFcn = createCallbackFcn(app, @tanxButtonPushed, true);
            app.tanxButton.Text = 'tan(x)';
            app.sinhxButton = uibutton(app.TrigPanel, 'push');
            app.sinhxButton.ButtonPushedFcn = createCallbackFcn(app, @sinhxButtonPushed, true);
            app.sinhxButton.Text = 'sinh(x)';
            app.coshxButton = uibutton(app.TrigPanel, 'push');
            app.coshxButton.ButtonPushedFcn = createCallbackFcn(app, @coshxButtonPushed, true);
            app.coshxButton.Text = 'cosh(x)';
            app.tanhxButton = uibutton(app.TrigPanel, 'push');
            app.tanhxButton.ButtonPushedFcn = createCallbackFcn(app, @tanhxButtonPushed, true);
            app.tanhxButton.Text = 'tanh(x)';
            app.arcsinxButton = uibutton(app.TrigPanel, 'push');
            app.arcsinxButton.ButtonPushedFcn = createCallbackFcn(app, @arcsinxButtonPushed, true);
            app.arcsinxButton.Text = 'arcsin(x)';
            app.arccosxButton = uibutton(app.TrigPanel, 'push');
            app.arccosxButton.ButtonPushedFcn = createCallbackFcn(app, @arccosxButtonPushed, true);
            app.arccosxButton.Text = 'arccos(x)';
            app.arctanxButton = uibutton(app.TrigPanel, 'push');
            app.arctanxButton.ButtonPushedFcn = createCallbackFcn(app, @arctanxButtonPushed, true);
            app.arctanxButton.Text = 'arctan(x)';
            app.arsinhxButton = uibutton(app.TrigPanel, 'push');
            app.arsinhxButton.ButtonPushedFcn = createCallbackFcn(app, @arsinhxButtonPushed, true);
            app.arsinhxButton.Text = 'arsinh(x)';
            app.arcoshxButton = uibutton(app.TrigPanel, 'push');
            app.arcoshxButton.ButtonPushedFcn = createCallbackFcn(app, @arcoshxButtonPushed, true);
            app.arcoshxButton.Text = 'arcosh(x)';
            app.artanhxButton = uibutton(app.TrigPanel, 'push');
            app.artanhxButton.ButtonPushedFcn = createCallbackFcn(app, @artanhxButtonPushed, true);
            app.artanhxButton.Text = 'artanh(x)';
            app.AngleTypeSwitchLabel = uilabel(app.TrigPanel);
            app.AngleTypeSwitchLabel.Text = 'AngleType';
            app.AngleTypeSwitch = uiswitch(app.TrigPanel, 'slider');
            app.AngleTypeSwitch.Items = {'Rad', 'Deg'};
            app.AngleTypeSwitch.Value = 'Rad';
            app.BasicPanel = uipanel(app.UIFigure);
            app.BasicPanel.Title = 'Basic';
            app.numberone = uibutton(app.BasicPanel, 'push');
            app.numberone.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numberone.Text = '1';
            app.numberseven = uibutton(app.BasicPanel, 'push');
            app.numberseven.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numberseven.Text = '7';
            app.numbereight = uibutton(app.BasicPanel, 'push');
            app.numbereight.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numbereight.Text = '8';
            app.numbernine = uibutton(app.BasicPanel, 'push');
            app.numbernine.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numbernine.Text = '9';
            app.numberfour = uibutton(app.BasicPanel, 'push');
            app.numberfour.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numberfour.Text = '4';
            app.numberfive = uibutton(app.BasicPanel, 'push');
            app.numberfive.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numberfive.Text = '5';
            app.numbersix = uibutton(app.BasicPanel, 'push');
            app.numbersix.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numbersix.Text = '6';
            app.addition = uibutton(app.BasicPanel, 'push');
            app.addition.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.addition.Text = '+';
            app.numberzero = uibutton(app.BasicPanel, 'push');
            app.numberzero.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numberzero.Text = '0';
            app.buttonEqual = uibutton(app.BasicPanel, 'push');
            app.buttonEqual.ButtonPushedFcn = createCallbackFcn(app, @buttonEqualPushed, true);
            app.buttonEqual.Text = '=';
            app.subtraction = uibutton(app.BasicPanel, 'push');
            app.subtraction.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.subtraction.Text = '-';
            app.numbertwo = uibutton(app.BasicPanel, 'push');
            app.numbertwo.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numbertwo.Text = '2';
            app.numberthree = uibutton(app.BasicPanel, 'push');
            app.numberthree.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.numberthree.Text = '3';
            app.divide = uibutton(app.BasicPanel, 'push');
            app.divide.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.divide.Text = '/';
            app.multi = uibutton(app.BasicPanel, 'push');
            app.multi.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.multi.Text = '*';
            app.LeftBracket = uibutton(app.BasicPanel, 'push');
            app.LeftBracket.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.LeftBracket.Text = '(';
            app.RightBracket = uibutton(app.BasicPanel, 'push');
            app.RightBracket.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.RightBracket.Text = ')';
            app.piButton = uibutton(app.BasicPanel, 'push');
            app.piButton.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.piButton.Text = 'pi';
            app.buttonEqual_2 = uibutton(app.BasicPanel, 'push');
            app.buttonEqual_2.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.buttonEqual_2.Text = 'i';
            app.ANSButton = uibutton(app.BasicPanel, 'push');
            app.ANSButton.ButtonPushedFcn = createCallbackFcn(app, @ANSButtonPushed, true);
            app.ANSButton.Text = '+/- ANS';
            app.DELButton = uibutton(app.BasicPanel, 'push');
            app.DELButton.ButtonPushedFcn = createCallbackFcn(app, @DELButtonPushed, true);
            app.DELButton.Text = 'DEL';
            app.multi_2 = uibutton(app.BasicPanel, 'push');
            app.multi_2.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.multi_2.Text = '.';
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Graph';
            app.aButton = uibutton(app.GraphPanel, 'push');
            app.aButton.ButtonPushedFcn = createCallbackFcn(app, @aButtonPushed, true);
            app.aButton.Text = 'a=';
            app.bButton = uibutton(app.GraphPanel, 'push');
            app.bButton.ButtonPushedFcn = createCallbackFcn(app, @bButtonPushed, true);
            app.bButton.Text = 'b=';
            app.cButton = uibutton(app.GraphPanel, 'push');
            app.cButton.ButtonPushedFcn = createCallbackFcn(app, @cButtonPushed, true);
            app.cButton.Text = 'c=';
            app.aoutput = uieditfield(app.GraphPanel, 'text');
            app.aoutput.Value = '0';
            app.boutput = uieditfield(app.GraphPanel, 'text');
            app.boutput.Value = '0';
            app.coutput = uieditfield(app.GraphPanel, 'text');
            app.coutput.Value = '0';
            app.SolveQuad = uibutton(app.GraphPanel, 'push');
            app.SolveQuad.ButtonPushedFcn = createCallbackFcn(app, @SolveQuadButtonPushed, true);
            app.SolveQuad.Text = 'Solve';
            app.RootOutput = uitextarea(app.GraphPanel);
            app.RootOutput.Value = {'find the roots of a linear or quadratic. where'; 'y = ax^2+bx+c'; 'or'; 'y = bx + c;'};
            app.UIAxes = uiaxes(app.GraphPanel);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ScientificOperationPanel = uipanel(app.UIFigure);
            app.ScientificOperationPanel.Title = 'Scientific Operation';
            app.lnxButton = uibutton(app.ScientificOperationPanel, 'push');
            app.lnxButton.ButtonPushedFcn = createCallbackFcn(app, @lnxButtonPushed, true);
            app.lnxButton.Text = 'ln(x)';
            app.exButton = uibutton(app.ScientificOperationPanel, 'push');
            app.exButton.ButtonPushedFcn = createCallbackFcn(app, @exButtonPushed, true);
            app.exButton.Text = 'e^x';
            app.xButton_3 = uibutton(app.ScientificOperationPanel, 'push');
            app.xButton_3.ButtonPushedFcn = createCallbackFcn(app, @xButton_3Pushed, true);
            app.xButton_3.Text = '1/x';
            app.xButton = uibutton(app.ScientificOperationPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x!';
            app.xButton_2 = uibutton(app.ScientificOperationPanel, 'push');
            app.xButton_2.ButtonPushedFcn = createCallbackFcn(app, @xButton_2Pushed, true);
            app.xButton_2.Text = '10^x';
            app.log10xButton = uibutton(app.ScientificOperationPanel, 'push');
            app.log10xButton.ButtonPushedFcn = createCallbackFcn(app, @log10xButtonPushed, true);
            app.log10xButton.Text = 'log10(x)';
            app.RandButton = uibutton(app.ScientificOperationPanel, 'push');
            app.RandButton.ButtonPushedFcn = createCallbackFcn(app, @RandButtonPushed, true);
            app.RandButton.Text = 'Rand';
            app.angleButton = uibutton(app.ScientificOperationPanel, 'push');
            app.angleButton.ButtonPushedFcn = createCallbackFcn(app, @angleButtonPushed, true);
            app.angleButton.Text = 'angle';
            app.xButton_4 = uibutton(app.ScientificOperationPanel, 'push');
            app.xButton_4.ButtonPushedFcn = createCallbackFcn(app, @xButton_4Pushed, true);
            app.xButton_4.Text = '|x|';
            app.x3Button = uibutton(app.ScientificOperationPanel, 'push');
            app.x3Button.ButtonPushedFcn = createCallbackFcn(app, @x3ButtonPushed, true);
            app.x3Button.Text = 'x^3';
            app.xyButton = uibutton(app.ScientificOperationPanel, 'push');
            app.xyButton.ButtonPushedFcn = createCallbackFcn(app, @xyButtonPushed, true);
            app.xyButton.Text = 'x^y';
            app.x2Button = uibutton(app.ScientificOperationPanel, 'push');
            app.x2Button.ButtonPushedFcn = createCallbackFcn(app, @x2ButtonPushed, true);
            app.x2Button.Text = 'x^2';
            app.x13Button = uibutton(app.ScientificOperationPanel, 'push');
            app.x13Button.ButtonPushedFcn = createCallbackFcn(app, @x13ButtonPushed, true);
            app.x13Button.Text = 'x^1/3';
            app.x05Button = uibutton(app.ScientificOperationPanel, 'push');
            app.x05Button.ButtonPushedFcn = createCallbackFcn(app, @x05ButtonPushed, true);
            app.x05Button.Text = 'x^0.5';
            app.x1yButton = uibutton(app.ScientificOperationPanel, 'push');
            app.x1yButton.ButtonPushedFcn = createCallbackFcn(app, @x1yButtonPushed, true);
            app.x1yButton.Text = 'x^1/y';
            app.storeY = uibutton(app.ScientificOperationPanel, 'push');
            app.storeY.ButtonPushedFcn = createCallbackFcn(app, @storeYButtonPushed, true);
            app.storeY.Text = 'y=';
            app.ClearYButton = uibutton(app.ScientificOperationPanel, 'push');
            app.ClearYButton.ButtonPushedFcn = createCallbackFcn(app, @ClearYButtonPushed, true);
            app.ClearYButton.Text = 'Clear Y';
            app.yValue = uieditfield(app.ScientificOperationPanel, 'numeric');
            app.yValue.Value = 1;
            app.eButton = uibutton(app.ScientificOperationPanel, 'push');
            app.eButton.ButtonPushedFcn = createCallbackFcn(app, @numberonePushed, true);
            app.eButton.Text = 'e';
            app.TextArea_2 = uitextarea(app.ScientificOperationPanel);
            app.TextArea_2.Value = {'EE =>'};
            app.ACButton = uibutton(app.UIFigure, 'push');
            app.ACButton.ButtonPushedFcn = createCallbackFcn(app, @ACButtonPushed, true);
            app.ACButton.Text = 'AC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calcv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end